classdef gooApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Welcome                       matlab.ui.container.TabGroup
        Tab_2                         matlab.ui.container.Tab
        WelcomeLabel                  matlab.ui.control.Label
        StructuredIlluminationButton  matlab.ui.control.Button
        ObliqueIlluminationButton     matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        IndividualOIPhaseTab          matlab.ui.container.Tab
        ConjugateCheckBox             matlab.ui.control.CheckBox
        RegionEditFieldLabel          matlab.ui.control.Label
        RegionEditField               matlab.ui.control.NumericEditField
        FTPSwitch                     matlab.ui.control.Switch
        Label                         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        RegionDropDown                matlab.ui.control.DropDown
        RegionDropDownLabel           matlab.ui.control.Label
        PL                            matlab.ui.control.Button
        PR                            matlab.ui.control.Button
        WavelengthumEditField         matlab.ui.control.NumericEditField
        WavelengthumEditFieldLabel    matlab.ui.control.Label
        PixelSizeYumEditFieldLabel    matlab.ui.control.Label
        PixelSizeXumEditField         matlab.ui.control.NumericEditField
        PixelSizeXumEditFieldLabel    matlab.ui.control.Label
        PixelSizeYumEditField         matlab.ui.control.NumericEditField
        ParametersPanel               matlab.ui.container.Panel
        samPerams                     matlab.ui.control.CheckBox
        tFile                         matlab.ui.control.EditField
        SaveBox                       matlab.ui.control.CheckBox
        LoadButton                    matlab.ui.control.Button
        PhaseReconstructionButton     matlab.ui.control.Button
        AmpAx                         matlab.ui.control.UIAxes
        PhaseAx                       matlab.ui.control.UIAxes
        StructuredTab                 matlab.ui.container.Tab
        StructRun                     matlab.ui.control.Button
        ParametersPanel_S             matlab.ui.container.Panel
        RegionDropDown_S              matlab.ui.control.DropDown
        RegionDropDown_2Label         matlab.ui.control.Label
        WavelengthumEditField_S       matlab.ui.control.NumericEditField
        WavelengthumEditField_2Label  matlab.ui.control.Label
        PixelSizeXumEditField_S       matlab.ui.control.NumericEditField
        PixelSizeXumEditField_2Label  matlab.ui.control.Label
        PixelSizeYumEditField_S       matlab.ui.control.NumericEditField
        PixelSizeYumEditField_2Label  matlab.ui.control.Label
        samPerams_2                   matlab.ui.control.CheckBox
        gPreveiw                      matlab.ui.control.Button
        gN                            matlab.ui.control.Button
        gP                            matlab.ui.control.Button
        ftSwitch                      matlab.ui.control.Switch
        gSelect                       matlab.ui.container.Panel
        groupNUM                      matlab.ui.control.NumericEditField
        NumberofgroupsEditFieldLabel  matlab.ui.control.Label
        StructLoad                    matlab.ui.control.Button
        groupPrev                     matlab.ui.control.UIAxes
        SRPhaseTab                    matlab.ui.container.Tab
        InformationPanel              matlab.ui.container.Panel
        ThicknessCheckBox             matlab.ui.control.CheckBox
        Stats                         matlab.ui.control.Label
        ThickSwitch                   matlab.ui.control.Switch
        Image_2                       matlab.ui.control.Image
        Paramiters2                   matlab.ui.container.Panel
        NIM                           matlab.ui.control.NumericEditField
        RefactiveInexMediumnimEditFieldLabel  matlab.ui.control.Label
        NS                            matlab.ui.control.NumericEditField
        RefractiveIndexSamplensEditFieldLabel  matlab.ui.control.Label
        SRQPIMapButton                matlab.ui.control.Button
        threshAx                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        COMPLEX_FEILD % Description
        IMAGES % all loaded images
        CURRENT_IMG % current image being displayed
        PHASES % generated Phase images
        CURRENT_PHASE % current phase being displayed
        TOTAL_IMG % # of img loaded
        PERAMS % arguments for da math
        SAVED % keeps tracks of when a peram is saved
        FILTERS % colections of all filters
        CIRCS % colections of all circles 
        STATE = "init" % Description
        dRegion = 1; % Description
        SUMF % sum of filters
        SUMC % sum of circs
        Circ0 % for the lateral res factor
        MaskThic % Description
        MAP % Description
        DROPDOWN cell % Description
        GROUPS % Description
        NUM_GROUPS % Description
        MODE % Description
        GROUP_LIM % Description
        ORDER % Description
    end

    methods (Access = private)

        function [results] = setVis(app, componet, state)
            if length(componet) > 1
                for i = 1:length(componet)
                    componet(i).Visible = state;
                    if componet(i) == "matlab.ui.control.UIAxes"
                        axis(componet(i), 'off')
                    end
                end
            else
                componet.Visible = state;
                if componet == "matlab.ui.control.UIAxes"
                        axis(componet, 'off')
                end

                results = 0;
            end

        end

        function results = getVal(app, componet)
            app = app;
            results = componet.Value;

        end

        function ComplexFieldNorm = phaseNorm(app,ComplexField)

            phase = angle(ComplexField);

            MIN = min(min(phase)); % idk why double min

            ComplexFieldNorm = ComplexField.*exp(-1j*MIN);

        end

        function  [holo_rec2, phaseImg] = Ophase_rec(app,fil, dx, dy, lambda, region, save)
            
            class(fil)
            disp(fil)
            [path,name,ext] = fileparts(fil);

            % Read hologram image and convert it to double precision
            holo = double(imread(fil));
                        % Get the size of the hologram
            [N,M] = size(holo);
            % Create a meshgrid for the hologram
            [m,n] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);
            % Calculate the Fourier Transform of the hologram
            % and shift the zero-frequency component to the center
            ft_holo = fftshift(fft2(fftshift(holo)));
            %figure(15), imagesc(log(abs(ft_holo).^2)), title 'FT hologram'


            % Initialize a filter with zeros
            filter = zeros(N,M);
            % Create a filter mask for the desired region

            if region== 1
                filter(50:round(N/2-(N*0.19)),round(M/2+(M*0.18)):M-50) = 1; % 1nd quadrant
            elseif region== 2
                filter(1:round(N/2-(N*0.185)),1:round(M/2-(M*0.185))) = 1;  % 2nd quadrant
            elseif region== 3
                filter(round(N/2+(N*0.15)):N-40,40:round(M/2-(M*0.18))) = 1; % 3nd quadrant
            else
                filter(round(N/2+(N*0.075)):N-500,round(M/2+(M*0.07)):M-600) = 1; % 4nd quadrant
            end
            % Apply the filter to the Fourier Transform of the hologram
            ft_filtered_holo = ft_holo .* filter;

            filtered_spect = log(abs(ft_filtered_holo).^2);
            %figure(1), imagesc(filtered_spect)

            %Threshold the phase image
            % BW = imbinarize(filtered_spect, 15); %FOR BINARY
            BW = imbinarize(filtered_spect, 12); %FOR CONTINOUS
            %figure;colormap gray;imagesc(BW);axis image, title 'BW'

            % Limpiar la imagen, dejando solo la región más grande (círculo)
            BW = bwareafilt(BW, 1);

            % Rellenar el círculo binario para obtener un borde continuo y robusto
            BW_filled = imfill(BW, 'holes');

            % Detectar el borde del círculo rellenado
            edges = edge(BW_filled, 'Canny');

            % Extraer coordenadas del borde
            [y,x] = find(edges);

            % Ajuste robusto por mínimos cuadrados para círculo
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;

            % Parámetros del círculo
            center(1) = params(1);
            center(2) = params(2);
            radius = sqrt(center(1)^2 + center(2)^2 - params(3));

            % Visualización clara de resultados
            %figure; imshow(BW); hold on;
            viscircles([center(1), center(2)], radius, 'EdgeColor','b');
            plot(center(1), center(2), 'r+', 'MarkerSize', 20, 'LineWidth', 2);

            % Resultados numéricos
            fprintf('Centro detectado: (%.2f, %.2f)\n', center(1), center(2));
            fprintf('Radio detectado: %.2f píxeles\n', radius);

            circ = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ(r,p) = 0;
                    end
                end
            end
            filtered_spect = ft_filtered_holo.*circ;
            %figure;colormap gray;imagesc(log(abs(filtered_spect)));title 'filtrado circular'


            [~,idx] = max(filtered_spect(:));
            % Get the maximum values of fx and fy
            [fy_max,fx_max] = ind2sub([N,M],idx);
            holo_rec = fftshift(ifft2(fftshift(filtered_spect)));
            % Find the maximum value in the filtered spectrum
            % Define wavenumber
            k = 2 * pi / lambda;
            % Calculate the center frequencies for fx and fy
            fx_0 = M/2;
            fy_0 = N/2;

            % Calculate the angles for the compensation wave
            theta_x = asin((fx_0 - fx_max +1) * lambda / (M * dx));
            theta_y = asin((fy_0 - fy_max +1) * lambda / (N * dy));
            % Calculate the reference wave
            ref = exp(1i * k * (sin(theta_x) * m * dx + sin(theta_y) * n * dy));
            % Apply the reference wave to the hologram reconstruction
            holo_rec2 = holo_rec .* (-ref);

            phase = angle(holo_rec2);
            %figure, imagesc(phase), colormap gray, title 'Phase reconstruida'
            phaseImg = imagesc(phase);
            FT_holo = FT(holo_rec2);
            %figure, imagesc(log(abs(FT_holo).^2)), colormap gray, title 'FT centrado'

            if save == 1
                fol = string(datetime("today","Format","uuuu-MM-dd"));
                mkdir(fol);
                phaseName = strcat(fol,"\",name,"_OI_DHM_output",ext);
                imwrite(phase,phaseName);
            end

        end

        function [newFilter, circ, rad] = circular_filter(app,ComplexField, newCompleField_0, TH)
            % TH = 15; %FOR BINARY
            %TH = 12; %FOR CONTINOUS
            CF0 = FT(ComplexField);
            CF0_BW = imbinarize(log(abs(CF0).^2), TH);
            % %figure, imagesc(CF0_BW)
            [filtrado0, circ0, rad] = app.findcircle(CF0_BW, CF0);
            newFilter = FT(newCompleField_0).*circ0;
            circ = circ0;
        end

        function [filter, circ, rad] = findcircle(app,CF0_BW, CF0)
            %Threshold the phase image
            app = app;
            BW = CF0_BW;
            [N,M] = size(CF0_BW);
            %figure;colormap gray;imagesc(BW);axis image, title 'BW'

            % Limpiar la imagen, dejando solo la región más grande (círculo)
            BW = bwareafilt(BW, 1);

            % Rellenar el círculo binario para obtener un borde continuo y robusto
            BW_filled = imfill(BW, 'holes');

            % Detectar el borde del círculo rellenado
            edges = edge(BW_filled, 'Canny');

            % Extraer coordenadas del borde
            [y,x] = find(edges); 

            % Ajuste robusto por mínimos cuadrados para círculo
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;

            % Parámetros del círculo
            center(1) = params(1);
            center(2) = params(2);
            radius = sqrt(center(1)^2 + center(2)^2 - params(3));
            rad = radius;
            % Visualización clara de resultados
            %figure; imshow(BW); hold on;
            %viscircles([center(1), center(2)], radius, 'EdgeColor','b');
            %plot(center(1), center(2), 'r+', 'MarkerSize', 20, 'LineWidth', 2);

            % Resultados numéricos
            fprintf('Centro detectado: (%.2f, %.2f)\n', center(1), center(2));
            fprintf('Radio detectado: %.2f píxeles\n', radius);

            circ = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ(r,p) = 0;
                    end
                end
            end

            filter = CF0.*circ;
        end

        function [filt, circ, compfeild, rad,phaseImg] = phaseFilter(app,file, dx, dy, lambda, region, save)
            disp('filter')
            disp(file)
            disp(dx)
            disp(dy)
            disp(lambda)
            disp(region)
            im = imread(string(file));
            thicImg = 0;
            %figure(8), imagesc(im), colormap gray, title 'Image preview'
                
            complexFeild  = app.Ophase_rec(file, dx,dy,lambda,region, save);
            compfeild = complexFeild;
            phaseImg = angle(complexFeild);
            %figure(21), imagesc(phaseImg), colormap gray, title 'phase'

            % seperate step
            [X,Y] = size(compfeild);
            zero_Phase = app.phaseNorm(complexFeild);
            app.thickness(zero_Phase,X,Y, lambda,1.52,1,0);
            [f, c, rad] = app.circular_filter(complexFeild,zero_Phase,12);
            filt = f;
            circ = c;

        end

        function [THICC, thicImg] = thickness(app, phaseDeg, x, y , lambda, ns, nim, check)
            
            THICC= (phaseDeg * lambda)/ (2* pi * (ns-nim));

            if check == 1 % creates plot to check if thickness center allines w/ center
                %disp(1)

                prefil = improfile(THICC, x,y);

                %figure(8), imagesc(THICC), colormap hot, title '0 deg thickness'
                hold on
                plot(x, y, 'c-', 'LineWidth', 2); % 'c-' for cyan line
                hold off


                %plot of the profiles
                %figure(6)
                plot(prefil)
            end
        end

        function [mask_RAW, thickness] = phaseSum(app, filters, circs, ns, nim, lambda)

            %figure, imagesc((abs(filters).^0.1)), colormap gray, title 'sum of filter'
            inF = IFT(filters);
            phase_SRDHM = angle(inF);
            %figure, imagesc(phase_SRDHM), colormap hot, title 'flor sin normalizar en frecuencias'

            %figure, imagesc(circs), colormap gray, title 'mask'

            normalization_weights = 1 ./ circs; % Inverso de la suma en cada punto
            normalization_weights(isinf(normalization_weights)) = 0; % Manejar división por cero
            %figure, imagesc(normalization_weights), colormap gray, title 'inverse mask'

            normalized_circles = filters.* normalization_weights;
            %figure, imagesc(log(abs(normalized_circles).^2)), colormap gray, title 'mask'
            mask_RAW = log(abs(normalized_circles).^2);
            inFL = IFT(normalized_circles);
            pSD = angle(inFL);
            %figure(21), imagesc(pSD), colormap hot, title 'phase of flower'
            thickness = (pSD * lambda)/ (2* pi * (ns-nim));
            
            %disp(circs);
            %disp(ratio);
            

        end
        
        function results = regionRec(app, axis, region)
             w = xlim(app.PhaseAx);
             w = w(2)/2;
             h = ylim(app.PhaseAx);
             h = h(2)/2;
            switch region

                case 1
                    rectangle(axis, Position= [w 0 w h-180]);
                case 2
                    rectangle(axis,Position= [0 0 w h] );
                case 3
                    rectangle(axis, Position= [0 h w h-180]);
                case 4
                    rectangle(axis,Position= [w h w h-180] );
                    
            end

            
        end
        
        function results = setAxis(app, uiAxis, a, vis, title, cMap)
            colormap(uiAxis, cMap);
            if title == "0"
                uiAxis.Title.String = uiAxis.Title.String;
            else
                uiAxis.Title.String = title;
            end
            uiAxis.Visible = vis;
            axis(uiAxis, a);
            results = 1;
            
        end
        
        function results = setAxisLim(app, ui, x, y)
                ui.XLim = [0,x];
                ui.YLim = [0,y];
                results = 1;
            
        end
        
        function threshold = getThresh(app,filterdHolo)

            [x,y] = size(filterdHolo);
            p1M = min(log(abs(filterdHolo(:,1))));
            step = 100;
            i = x;
            while p1M < 0
                p1M = min(log(abs(filterdHolo(1:i,1))));
                disp(p1M)
                i = i - step;
            end
            threshold = 1.2*p1M;

        end
        
        function circ2 = circ_Struc(app,H1Filt,TH, N)
            BW = imbinarize(abs(H1Filt).^.2, TH);
            BW = bwareafilt(BW, 1);
            BW_filled = imfill(BW, 'holes');
            edges = edge(BW_filled, 'Canny');

            [y,x] = find(edges);
            A = [-2*x, -2*y, ones(size(x))];
            b = -(x.^2 + y.^2);
            params = A\b;
            center(1) = params(1);
            center(2) = params(2);
            radius = ceil(sqrt(center(1)^2 + center(2)^2 - params(3)));

            % Visualización clara de resultados
            % figure; imshow(BW); hold on;
            % viscircles([center(1), center(2)], radius, 'EdgeColor','b');
            % plot(center(1), center(2), 'r+', 'MarkerSize', 20, 'LineWidth', 2);

            circ2 = ones(N);
            for r = 1:N
                for p = 1:N
                    if sqrt((r-center(2))^2+(p-center(1))^2)>radius
                        circ2(r,p) = 0;
                    end
                end
            end
            % Circ_filtered_spectrum1 = ft_filtered_holo.*circ;
        end
        
        function results = StateSwitch(app, src, event)
            if src == app.ObliqueIlluminationButton
                %msgbox("oi")
                app.Welcome.Visible = "off";
                app.TabGroup.Visible = "on";
                delete(app.StructuredTab)
                app.STATE = 'init';
                app.MODE = "OI";
            elseif src == app.StructuredIlluminationButton
                %msgbox("si")
                app.Welcome.Visible = "off";
                app.TabGroup.Visible = "on";
                delete(app.IndividualOIPhaseTab)
                app.STATE = 'init';
                app.MODE = "SI";
            end
            
        end
        
        function [Cmax1, Rmax1, Cmax2 , Rmax2] = max_holo(app,H1Filt)
            %figure; colormap gray; imagesc(abs(H1Filt));axis image;
            AbsFT = abs(H1Filt);
            maxValue = max(AbsFT(:));%value of the maximum peaks
            [rowsofMaxPk, colsofMaxPk] = find(AbsFT == maxValue);

            Cmax1 = colsofMaxPk;%Horizontal position of the peak of Dplus
            Rmax1 = rowsofMaxPk;%Vertical position of the peak of Dplus

            clear maxValue rowsofMaxPk colsofMaxPk

            AbsFT(Rmax1-2:Rmax1+2,Cmax1-2:Cmax1+2) = 0;%% ASK SOFI
            %figure; colormap gray; imagesc(AbsFT);axis image;
            maxValue = max(AbsFT(:));%value of the maximum peaks
            [rowsofMaxPk, colsofMaxPk] = find(AbsFT == maxValue);

            Cmax2 = colsofMaxPk;%Horizontal position of the peak of Dminus
            Rmax2 = rowsofMaxPk;%Vertical position of the peak of Dminus

            clear AbsFT maxValue rowsofMaxPk colsofMaxPk
        end

            % absHolo = abs(filter);
            % maxVal = max(absHolo(:));
            % [row, col] = find(absHolo == maxVal);
            % 
            % Cmax1 = col;
            % Rmax1 = row;
            % 
            % clear maxVal row col
            % absHolo(Rmax1-2:Rmax1+2,Cmax1-2:Cmax1+2) = 0; % blocks peak the was found above
            % maxVal = max(absHolo(:));
            % %figure; colormap gray; imagesc(absHolo);axis image;
            % [row, col] = find(absHolo == maxVal);
            % 
            % Cmax2 = col;
            % Rmax2 = row;
            % 
            % clear maxVal row col


        function [std_temp_gplus, std_temp_gminus, angles, FTHolo] = min_holo(app, H1Filt, H2Filt,paso, Cmax1, Rmax1, Cmax2 , Rmax2)
            FTHolo(:,:,1) = H1Filt;
            FTHolo(:,:,2) = H2Filt;

            angles = 0.0001:paso:2*pi;

            std_temp_gplus = ones(1,length(angles));
            std_temp_gminus = ones(1,length(angles));

            tic
            parfor i = 1:length(angles)
                v = angles(i);
                % Demodulation using the optimized values
                theta_final = [0, v];
                [Gdemod] =  app.demComp2SIDHM(theta_final, FTHolo);
                Gplus_demod = Gdemod(:,:,1);
                Gminus_demod = Gdemod(:,:,2);
                std_temp_gplus(1,i) =  abs(Gplus_demod(Rmax2,Cmax2))/abs(abs(Gplus_demod(Rmax2,Cmax2)) + abs(Gplus_demod(Rmax1,Cmax1)));
                std_temp_gminus(1,i) =  abs(Gminus_demod(Rmax1,Cmax1))/abs(abs(Gminus_demod(Rmax2,Cmax2)) + abs(Gminus_demod(Rmax1,Cmax1)));
            end
            toc
            % ftHolo(:,:,1) = filter1;
            % ftHolo(:,:,2) = filter2;
            % 
            % ang = .0001:step:2*pi;
            % 
            % gplus = ones(1,length(ang));
            % gminus = ones(1,length(ang));
            % 
            % tic
            % parfor i = 1:length(ang)
            %     v = ang(i)
            %     theta = [0,v];
            %     [dmod] = demComp2SIDHM(app,theta, ftHolo);
            %     dPlus = dmod(:,:,1);
            %     dMinus = dmod(:,:,2);
            %     gplus(1,i) = abs(dPlus(rmax2,cmax2))/abs(abs(dPlus(rmax2,cmax2)) + abs(dPlus(rmax1,cmax1)));
            %     gminus(1,i) = abs(dMinus(rmax1,cmax1))/abs(abs(dMinus(rmax2,cmax2)) + abs(dMinus(rmax1,cmax1)));
            % 
            % end
            % toc


        end

        
        function [D] = demComp2SIDHM(app, theta, H)

            % Initialize some useful values
            [X, Y, no] = size(H); % number of training examples

            % You need to return the following variables correctly
            D = zeros(X,Y,no);

            M = 1/2*[exp(1i*theta(1)) exp(-1i*theta(1));exp(1i*theta(2)) exp(-1i*theta(2))];
            % Minv = pinv(M);
            Minv = inv(M);

            D(:,:,1) = Minv(1,1).*H(:,:,1) + Minv(1,2).*H(:,:,2);
            D(:,:,2) = Minv(2,1).*H(:,:,1) + Minv(2,2).*H(:,:,2);

        end
       
        function [circ1,zero_phase6] = demodulation_Gminus(app, std_temp_gminus, angles,FTHolo, dx, dy, lambda, circ2)
            posicion = find(std_temp_gminus == min(std_temp_gminus));
            theta_final = [0, angles(posicion)];
            [Gdemod] =  app.demComp2SIDHM(theta_final, FTHolo);
            Gminus_demod = Gdemod(:,:,2);

            gminus = fftshift(ifft2(fftshift(Gminus_demod)));
            gminus = gminus*-1;
            % ref_minus = phase_rec(gminus, dx, dy, lambda, region, false);
            [ref_minus] = app.Sphase_rec(gminus, dx, dy, lambda, circ2);
            Gminus = gminus .*ref_minus;

            Gminus1 = FT(Gminus);
            % Find max in A_before
            [~, idx_before] = max(log(abs(Gminus_demod(:)).^2));
            [row_before, col_before] = ind2sub(size(Gminus_demod), idx_before);

            % Find max in A_after
            [~, idx_after] = (max(log(abs(Gminus1(:)).^2)));
            [row_after, col_after] = ind2sub(size(Gminus1), idx_after);

            % Calculate displacement
            delta_row = row_after - row_before;
            delta_col = col_after - col_before;
            circ1 = circshift(circ2, [delta_row, delta_col]);

            FT_centrada6 = FT(Gminus).*circ1;
            Centered_complex_field6 = IFT(FT_centrada6);
            zero_phase6 = (Centered_complex_field6);
        end


        function [circ1,zero_phase6] = demodulation_Gplus(app,std_temp_gplus, angles,FTHolo, dx,dy, lambda, circ2)
            posicion = find(std_temp_gplus == min(std_temp_gplus));
            theta_final = [0, angles(posicion)];
            [Gdemod] =  app.demComp2SIDHM(theta_final, FTHolo);
            Gplus_demod = Gdemod(:,:,1);
            gplus = fftshift(ifft2(fftshift(Gplus_demod)));
            gplus = gplus*-1;
            [ref_gplus] = app.Sphase_rec(gplus, dx, dy, lambda, circ2);
            Gplus = gplus .* ref_gplus;

            Gminus1 = FT(Gplus);
            % Find max in A_before
            [~, idx_before] = max(log(abs(Gplus_demod(:)).^2));
            [row_before, col_before] = ind2sub(size(Gplus_demod), idx_before);

            % Find max in A_after
            [~, idx_after] = (max(log(abs(Gminus1(:)).^2)));
            [row_after, col_after] = ind2sub(size(Gminus1), idx_after);

            % Calculate displacement
            delta_row = row_after - row_before;
            delta_col = col_after - col_before;
            circ1 = circshift(circ2, [delta_row, delta_col]);

            FT_centrada6 = FT(Gplus).*circ1;
            Centered_complex_field6 = IFT(FT_centrada6);
            zero_phase6 = (Centered_complex_field6);
        end


        function [std_temp_gplus, std_temp_gminus, angles, FTHolo]=minimization(app, H1Filt, H2Filt,paso, Cmax1, Rmax1, Cmax2 , Rmax2)
            FTHolo(:,:,1) = H1Filt;
            FTHolo(:,:,2) = H2Filt;

            angles = 0.0001:paso:2*pi;

            std_temp_gplus = ones(1,length(angles));
            std_temp_gminus = ones(1,length(angles));

            tic
            parfor i = 1:length(angles)
                v = angles(i);
                % Demodulation using the optimized values
                theta_final = [0, v];
                [Gdemod] =  app.demComp2SIDHM(theta_final, FTHolo);
                Gplus_demod = Gdemod(:,:,1);
                Gminus_demod = Gdemod(:,:,2);
                std_temp_gplus(1,i) =  abs(Gplus_demod(Rmax2,Cmax2))/abs(abs(Gplus_demod(Rmax2,Cmax2)) + abs(Gplus_demod(Rmax1,Cmax1)));
                std_temp_gminus(1,i) =  abs(Gminus_demod(Rmax1,Cmax1))/abs(abs(Gminus_demod(Rmax2,Cmax2)) + abs(Gminus_demod(Rmax1,Cmax1)));
            end
            toc
        end


        function  [ref] = Sphase_rec(app,filename, dx, dy, lambda, circ)

            % Main function to perform phase reconstruction
            holo = filename;

            % Get the size of the hologram
            [N,M] = size(holo);
            % Create a meshgrid for the hologram
            [m,n] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);

            % Calculate the Fourier Transform of the hologram and shift the zero-frequency component to the center
            ft_holo = fftshift(fft2(fftshift(holo)));

            % Initialize a filter with zeros
            filter = zeros(N,M);
            % Create a filter mask for the desired region
            filter(2:round(N/2-(N*0.18)),2:M) = 1; % 1nd quadrant

            % Apply the filter to the Fourier Transform of the hologram
            ft_filtered_holo = ft_holo .* filter;

            Circ_filtered_spectrum1 = ft_filtered_holo.*circ;

            [~,idx] = max(Circ_filtered_spectrum1(:));
            % Get the maximum values of fx and fy
            [fy_max,fx_max] = ind2sub([N,M],idx);

            % Find the maximum value in the filtered spectrum
            % Define wavenumber
            k = 2 * pi / lambda;
            % Calculate the center frequencies for fx and fy
            fx_0 = M/2;
            fy_0 = N/2;

            % Calculate the angles for the compensation wave
            theta_x = asin((fx_0 - fx_max +1) * lambda / (M * dx));
            theta_y = asin((fy_0 - fy_max +1) * lambda / (N * dy));
            % Calculate the reference wave
            ref = exp(1i * k * (sin(theta_x) * m * dx + sin(theta_y) * n * dy));
        end

    end

    methods (Access = public)

        function results = bTest(app,scr, event)
            if str2num(scr.Tag) == 0
                return;
            end

            group = scr.Value;
            oldG = app.GROUPS;
            imagePath = string(app.IMAGES(str2num(scr.Tag)));
            im = double(imread(imagePath));

            index = find(cellfun(@(x) isequal(x, im), app.GROUPS));

            if ~isempty(index)
                numRows = size(app.GROUPS, 1);
                r = mod(index - 1, numRows) + 1;
                c = floor((index - 1) / numRows) + 1;
                
                if c < size(app.GROUPS, 2)
                    app.GROUPS(r, c:end-1) = app.GROUPS(r, c+1:end);
                end
                app.GROUPS{r, end} = oldG{r, end};

                app.SAVED(r) = app.SAVED(r) - 1;
                disp(app.GROUPS)
            end

            idx_new_col = find(cellfun('isempty', app.GROUPS(group, :)), 1);

            if ~isempty(idx_new_col)
                app.GROUPS{group, idx_new_col} = im;
                app.SAVED(group) = app.SAVED(group) + 1;
                disp(app.GROUPS)
            end

            results = [];

        end
        %     if str2num(scr.Tag) == 0
        %         return
        %     end
        %     group = scr.Value;
        %     im = double(imread(string(app.IMAGES(str2num(scr.Tag)))));
        %     %figure; colormap gray;imagesc(im);axis image
        %     index = find(cellfun(@(x) isequal(x,im), app.GROUPS));%find(ismember(app.GROUPS, im));
        %     disp(index)
        %     if ~isempty(index)
        %         app.GROUPS{index} = "";
        %         r = mod(index-1, size(app.GROUPS,1)) + 1;
        %         app.SAVED(r) = app.SAVED(r) - 1;
        %         disp(app.GROUPS)
        %     end
        %     idx = find(cellfun('isempty', app.GROUPS), 1);
        %     disp(idx)
        %     % Add the element to the first empty cell, if any empty cell exists
        %     if ~isempty(idx)
        %        app.GROUPS{group, idx} = im;
        %        app.SAVED(group) = app.SAVED(group) + 1;
        %        disp(app.GROUPS)
        %     end
        %     disp(app.SAVED)
        %     %app.GROUPS(val,:) =
        % 
        % 
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.STATE = "Welcome";
            app.setVis(app.TabGroup,"off");
            app.ObliqueIlluminationButton.ButtonPushedFcn = @(src, event) StateSwitch(app,src,event);
            app.StructuredIlluminationButton.ButtonPushedFcn = @(src, event) StateSwitch(app,src,event);
            %delete(app.Tab)
            
        end

        % Button pushed function: PhaseReconstructionButton
        function PhaseReconstructionButtonPushed(app, event)
            inny = app.PERAMS; %[app.PixelSizeXumEditField.Value app.PixelSizeYumEditField.Value app.WavelengthumEditField.Value str2num(app.RegionDropDown.Value)];
            boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value,app.RegionDropDown.Value - 48];
            disp(app.TOTAL_IMG)
            if app.TOTAL_IMG > 1
                [n,m] = size(imread(string(app.IMAGES(1))));
                app.COMPLEX_FEILD = cell(1, app.TOTAL_IMG);
                app.PHASES = cell(1, app.TOTAL_IMG);
                app.FILTERS = cell(1, app.TOTAL_IMG);
                app.CIRCS = cell(1, app.TOTAL_IMG);
                app.SUMC = zeros(n,m);
                app.SUMF = zeros(n,m);
                for i = 1:app.TOTAL_IMG
                    %disp(string(app.IMAGES(i)))
                    %take fName and feed into the phase rec w/ out split into 2
                    %arrays and should just work?
                    fprintf("image: %s\n dx: %.2f dy: %.2f \nl: %.2f r: %.2f \n", string(app.IMAGES(i)), inny(i,1),inny(i,2),inny(i,3),inny(i,4))
                    im = string(app.IMAGES(i));
                    %disp(im)
                    if app.getVal(app.samPerams) == 0 && sum(inny(i,:)) ~= 0
                        [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, inny(i,1),inny(i,2),inny(i,3),inny(i,4), app.SaveBox.Value);
                    elseif sum(boxes) == 0
                        disp("error")
                        return
                    else
                        [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, boxes(1),boxes(2),boxes(3),boxes(4),app.SaveBox.Value);
                    end
                    %ttt= imagesc(phase);
                    app.COMPLEX_FEILD{i} = complexFeild;
                    app.PHASES{i} = (phase);
                    app.FILTERS{i} = filt;
                    app.CIRCS{i} = circ;
                    if i == 1
                        app.Circ0 = rad;
                    end
                    app.SUMF = filt + app.SUMF;
                    app.SUMC = circ + app.SUMC;
                    %app.PhaseImg.ImageSource = imagesc(phase);
                    %disp(class(app.FILTERS))

                    disp("done")
                end
                app.setAxis(app.PhaseAx,"off","on","phase",'gray');
                imagesc( app.PHASES{i}, 'Parent', app.PhaseAx);
                
            else
                im = string(app.IMAGES);
                [filt, circ,complexFeild, rad, phase] = app.phaseFilter(im, boxes(1),boxes(2),boxes(3),boxes(4), app.SaveBox.Value);
                app.COMPLEX_FEILD = complexFeild;
                app.PHASES = phase;
                app.PHASES = phase;
                app.FILTERS = filt;
                app.CIRCS = circ;
                app.Circ0 = rad;
                app.setAxis(app.PhaseAx,"off","on","phase",'gray');
                imagesc( phase, 'Parent', app.PhaseAx);
                
                
            end
            app.STATE = "PHASE";
            app.setVis(app.FTPSwitch, "on");
            app.setVis(app.ConjugateCheckBox, "on");
            %disp(app.PHASES)
            %[complexFeild, phase, ampl] = phaseRec(app.getVal(app.tFile), inny(1),inny(2),inny(3),inny(4),0);
            %app.COMPLEX_FEILD = complexFeild;
            %app.PhaseImg.ImageSource = image(phase);
            %imagesc(phase, 'Parent', app.PhaseAx);
            %imagesc(ampl, 'Parent', app.AmpAx);
            %app.setVis(app.PhaseAx,"on");


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %disp("boop");
            [fName, fPath]= uigetfile("MultiSelect","on","*.bmp");
            disp(app.SaveBox.Value)
            disp(isstring(fName(2)))
            FILE = strcat(fPath, fName); %% USE THIS SO IMG DIR DOESNT MATTER
            if isempty(fName)
                fName = " ";
            
            elseif class(fName(2)) == "cell"
                app.IMAGES = fName;
                app.TOTAL_IMG = size(fName, 2);
                app.PERAMS = zeros(app.TOTAL_IMG, 4);
                disp(app.PERAMS)
                app.SAVED = zeros(1, app.TOTAL_IMG);
                app.CURRENT_IMG = 1;
                img = imread(string(app.IMAGES(1)));
                [x,y] = size(img);
                app.setAxisLim(app.AmpAx, x, y);
                app.setAxisLim(app.PhaseAx, x, y);
                imagesc( img, 'Parent', app.AmpAx);
                ftI = fftshift(fft2(fftshift(img)));
                imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                colorbar(app.PhaseAx);
                app.regionRec(app.PhaseAx, app.dRegion)
                app.setVis([app.AmpAx, app.PL, app.PR], "on")
                axis(app.AmpAx, 'off')
                colorbar(app.PhaseAx);
                app.setVis([app.AmpAx, app.PL, app.PR], "on");
                app.tFile.Value = string(fName(1));
                axis(app.AmpAx, 'off')
                
            else 
                disp("else")
                app.IMAGES = string(fName);
                app.TOTAL_IMG = 1;
                app.PERAMS = zeros(app.TOTAL_IMG, 4);
                disp(app.PERAMS)
                app.SAVED = zeros(1, app.TOTAL_IMG);
                app.CURRENT_IMG = 1;
                img = imread(string(app.IMAGES));
                [x,y] = size(img);
                app.setAxisLim(app.AmpAx, x, y);
                app.setAxisLim(app.PhaseAx, x, y);
                imagesc(img, 'Parent', app.AmpAx);
                ftI = fftshift(fft2(fftshift(img)));
                imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                colorbar(app.PhaseAx);
                app.regionRec(app.PhaseAx, 1)
                app.setVis([app.AmpAx, app.PL, app.PR], "on")
                axis(app.AmpAx, 'off');
                app.tFile.Value = string(fName);
                axis(app.AmpAx, 'off')

            end
            app.STATE = "loaded";
            
        end

        % Button pushed function: SRQPIMapButton
        function SRQPIMapButtonPushed(app, event)
        sf = app.SUMF;
        sc = app.SUMC;
        [map, thic] = app.phaseSum(sf,sc, app.NS.Value, app.NIM.Value, app.WavelengthumEditField.Value);
        app.MaskThic = thic;
        imagesc(map, "Parent", app.threshAx);
        app.MAP = map;
        [x,y] = size(map);
        app.setAxisLim(app.threshAx, x,y);
        axis(app.threshAx,"off");
        colormap(app.threshAx, "gray");
        norm0 = app.phaseNorm(app.Circ0);
        mBW = imbinarize(map);
        figure, imagesc(abs(mBW)), colormap gray
        
        [f,c, rad] = app.findcircle(mBW, map);
        ratio = round(rad./app.Circ0,2);
        St = strcat("The lateral resolution has been improved by a factor of ", string(ratio));
        app.Stats.Text = string(St);
        app.setVis(app.InformationPanel, "on");

        end

        % Button pushed function: PR
        function previewNext(app, event)
            cur = app.CURRENT_IMG + 1;
            region = app.RegionDropDown.Value - 48;
            boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value, region];
            saved = zeros(1,app.TOTAL_IMG);
            prev = cur - 1;
            disp("test")
            disp(saved)
            
            if cur > app.TOTAL_IMG
                cur = 1;
            else
                app.CURRENT_IMG = cur;
                disp(app.CURRENT_IMG)
                img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                app.setVis(app.AmpAx,"off");
                imagesc(img, 'Parent', app.AmpAx);
                app.setVis(app.AmpAx,"on");
                app.setVis(app.PhaseAx,"off");
                axis(app.AmpAx, 'off')
                if app.FTPSwitch.Visible == "on" && app.FTPSwitch.Value == "Phase"
                    imagesc(app.PHASES{app.CURRENT_IMG},'Parent', app.PhaseAx);
                    app.setAxis(app.PhaseAx,'off','on',"Phase","gray");
                else
                    ftI = fftshift(fft2(fftshift(img)));
                    imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                    app.regionRec(app.PhaseAx, (app.RegionDropDown.Value - 48));
                    app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                end
                app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
            end
            
            checkSum = sum((app.PERAMS(prev,:) == boxes));

            disp (checkSum)

            if checkSum ~= 4 && saved(prev) == 0
                app.PERAMS(prev,:)= [app.PixelSizeXumEditField.Value app.PixelSizeYumEditField.Value app.WavelengthumEditField.Value region];
                app.SAVED(prev) = 1;
                disp(app.PERAMS)
                disp(saved)
            end
            if app.getVal(app.samPerams) == 0
                if app.SAVED(app.CURRENT_IMG) == 1
                    app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                    app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                    app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                    app.RegionDropDown.Value = app.PERAMS(app.CURRENT_IMG, 4);
                else
                    app.PixelSizeXumEditField.Value = 0;
                    app.PixelSizeYumEditField.Value = 0;
                    app.WavelengthumEditField.Value = 0;
                    app.RegionDropDown.Value = 1;
                end
            end
            
            % disp(app.PERAMS(app.CURRENT_IMG,:) == boxes);
            % if size(app.PERAMS) >= app.CURRENT_IMG
            %     app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
            %     app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
            %     app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
            %     str2num(app.RegionDropDown.Value) = app.PERAMS(app.CURRENT_IMG, 4);
            % 
            % else 
            %     app.PixelSizeXumEditField.Value = 0;
            %     app.PixelSizeYumEditField.Value = 0;
            %     app.WavelengthumEditField.Value = 0;
            %     str2num(app.RegionDropDown.Value) = 0;
            % end


        end

        % Button pushed function: PL
        function previewBack(app, event)
            cur = app.CURRENT_IMG - 1;
            region = app.RegionDropDown.Value - 48;
            boxes = [app.PixelSizeXumEditField.Value,app.PixelSizeYumEditField.Value,app.WavelengthumEditField.Value, region];
            saved = zeros(1,app.TOTAL_IMG);
            prev = cur + 1;
            disp("test")
            disp(saved)
            if cur < 1
                cur = 1;
            else
                app.CURRENT_IMG = cur;
                disp(app.CURRENT_IMG)
                img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                app.setVis(app.AmpAx,"off");
                imagesc(img, 'Parent', app.AmpAx);
                app.setVis(app.AmpAx,"on");
                app.setVis(app.PhaseAx,"off");
                axis(app.AmpAx, 'off')
                if app.FTPSwitch.Visible == "on" && app.FTPSwitch.Value == "Phase"
                    imagesc(app.PHASES{app.CURRENT_IMG},'Parent', app.PhaseAx);
                    app.setAxis(app.PhaseAx,'off','on',"Phase","gray");
                else
                    ftI = fftshift(fft2(fftshift(img)));
                    imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                    app.regionRec(app.PhaseAx, (app.RegionDropDown.Value - 48));
                    app.setAxis(app.PhaseAx,'off','on','Fourier transform','default');
                end
                app.tFile.Value = string(app.IMAGES(app.CURRENT_IMG));
            end
            checkSum = sum((app.PERAMS(prev,:) == boxes));

            if checkSum ~= 4 && saved(prev) == 0
                app.PERAMS(prev,:)= [app.PixelSizeXumEditField.Value app.PixelSizeYumEditField.Value app.WavelengthumEditField.Value region];
                app.SAVED(prev) = 1;
                disp(app.PERAMS)
                disp(saved)
            end
            if app.getVal(app.samPerams) == 0
                if app.SAVED(app.CURRENT_IMG) == 1
                    app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
                    app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
                    app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
                    app.RegionDropDown.Value = app.PERAMS(app.CURRENT_IMG, 4);
                else
                    app.PixelSizeXumEditField.Value = 0;
                    app.PixelSizeYumEditField.Value = 0;
                    app.WavelengthumEditField.Value = 0;
                    app.RegionDropDown.Value = 1;
                end
            end

            % if size(app.PERAMS) >= app.CURRENT_IMG
            %     app.PixelSizeXumEditField.Value = app.PERAMS(app.CURRENT_IMG, 1);
            %     app.PixelSizeYumEditField.Value = app.PERAMS(app.CURRENT_IMG, 2);
            %     app.WavelengthumEditField.Value = app.PERAMS(app.CURRENT_IMG, 3);
            %     str2num(app.RegionDropDown.Value) = app.PERAMS(app.CURRENT_IMG, 4);
            % 
            % else 
            %     app.PixelSizeXumEditField.Value = 0;
            %     app.PixelSizeYumEditField.Value = 0;
            %     app.WavelengthumEditField.Value = 0;
            %     str2num(app.RegionDropDown.Value) = 0;
            % end
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            value = app.RegionDropDown.Value - 48;
            %val2 = app.RegionDropDown_S.Value - 48;
            a = 1;
            if app.FTPSwitch.Value == "Phase" && app.FTPSwitch.Visible == "on"  
                return
            elseif app.STATE == "init"
                app.dRegion = value;
            else
                while a == 1
                    if app.TOTAL_IMG > 1
                        img = imread(string(app.IMAGES(app.CURRENT_IMG)));
                    else 
                        img = imread(string(app.IMAGES));
                    end
                    ftI = fftshift(fft2(fftshift(img)));
                    app.setVis(app.PhaseAx,"off");
                    imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                    app.regionRec(app.PhaseAx, value);
                    app.setVis(app.PhaseAx,"on");
                    axis(app.PhaseAx, 'off')
                    a = 0;
                end

            end
        end

        % Value changed function: FTPSwitch
        function FTPSwitchValueChanged(app, event)
            value = app.FTPSwitch.Value;
            
            if app.TOTAL_IMG > 1
                cur = imread(string(app.IMAGES(app.CURRENT_IMG)));
                ph = app.PHASES{app.CURRENT_IMG};
            else
                cur = imread(string(app.IMAGES));
                ph = app.PHASES;
            end

            switch value
                case "FT"
                    app.PhaseAx.Visible = 'off';
                    ftI = fftshift(fft2(fftshift(cur)));
                    imagesc(log10(abs(ftI)), 'Parent', app.PhaseAx);
                    app.regionRec(app.PhaseAx,(app.RegionDropDown.Value - 48));
                    app.setAxis(app.PhaseAx, "off", "on", "Fourier transform", "default");
                    app.setVis(app.ConjugateCheckBox, "off");
                    axis(app.PhaseAx, "off");
                case "Phase"
                    app.PhaseAx.Visible = 'off';
                    imagesc(ph, 'Parent', app.PhaseAx);
                    app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
                    app.setVis(app.ConjugateCheckBox, "on");
                    axis(app.PhaseAx, "off");
            end
        end

        % Value changed function: ConjugateCheckBox
        function ConjugateCheckBoxValueChanged(app, event)
            value = app.ConjugateCheckBox.Value;
            if app.TOTAL_IMG > 1
                c = app.COMPLEX_FEILD{app.CURRENT_IMG};
            else
                c = app.COMPLEX_FEILD;
            end

            if value == 1
                disp(value)
                p = angle(conj(c));
                app.PhaseAx.Visible = 'off';
                imagesc(p, 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
            else
                disp(value)
                p = angle(c);
                app.PhaseAx.Visible = 'off';
                imagesc(p, 'Parent', app.PhaseAx);
                app.setAxis(app.PhaseAx, "off", "on", "Phase", "gray");
            end


        end

        % Value changed function: ThickSwitch
        function ThickSwitchValueChanged(app, event)
            value = app.ThickSwitch.Value;
            [x,y] = size(app.MaskThic);
             prefil = improfile(app.MaskThic, x,y);
                
             if value == "Phase"
                %figure(8), imagesc(THICC), colormap hot, title '0 deg thickness'
                imagesc(app.MaskThic, "Parent", app.threshAx);
                colorbar(app.threshAx, 'southoutside');
                if app.ThicknessCheckBox.Value == 1
                    %put thick ness in fig it changes units :)
                end
                app.setAxis(app.threshAx,'off','on',"0", "gray");
             else
                 imagesc(app.MAP, "Parent", app.threshAx);
                 colorbar(app.threshAx,'off');
                 app.setAxis(app.threshAx,'off','on',"0", "gray");

             end


                %plot of the profiles
                %figure(6)
                
            
        end

        % Button pushed function: StructLoad
        function StructLoadButtonPushed(app, event)
            fig = app.gSelect;
            initY = 330;
            step = 30;
            app.NUM_GROUPS = app.getVal(app.groupNUM);
            app.SAVED = zeros(1,app.NUM_GROUPS);
            IMG = 0;
            
            if app.NUM_GROUPS < 1
                msgbox("ERROR: No groups","modal")
                return
            end
          
            opt = strings(1,app.NUM_GROUPS);
            val = 0:app.NUM_GROUPS;
            opt(1) = "Pick group"; 
            for i = 2:app.NUM_GROUPS+1
                opt(i) = strcat("Group ",string(i-1));
            end

            [f,p] = uigetfile("*.bmp","Select holograms",MultiSelect="on");

            holos = strcat(p,f);

            if size(holos,2) >= app.NUM_GROUPS && isfile(holos(app.NUM_GROUPS))
                IMG = length(holos);
                app.GROUP_LIM = round(length(holos)/app.NUM_GROUPS);
                app.IMAGES = strcat(p,f);
                app.TOTAL_IMG = IMG;
                app.GROUPS = cell(app.NUM_GROUPS,app.GROUP_LIM);
                app.ORDER = cell(app.NUM_GROUPS*app.GROUP_LIM);
                disp(class(app.GROUPS))
                disp(size(app.GROUPS))

            else
                msgbox("ERROR: No Images selected","modal")
                return
            end
           
            for j = 1:IMG
                app.DROPDOWN{i} = uidropdown(fig,"Position",[80 initY 80 25],"Items", opt,"ItemsData",val,"Tag",string(j),"ValueChangedFcn",@app.bTest);%uicontrol(fig,Style="popupmenu");
                %e = uidropdown(fig,"Items",opt);
                L(i) = uicontrol(fig,Style= "text");
                %c.Position = [80 initY 80 25];
                L(i).Position = [20 (initY + 5) 50 15];
                L(i).String = strcat("Image ",string(j));
                L(i).Tooltip = f(j);
                %c.String = opt;
                %set(c,"Callback",@app.bTest);
                initY = initY - step;
            end
            app.setVis([app.gPreveiw, app.StructRun, app.ParametersPanel_S], "on")

        end

        % Button pushed function: gPreveiw
        function gPreveiwButtonPushed(app, event)
            im = app.GROUPS(1,1);
            %[x,y] = size(im);
            %app.setAxisLim(app.groupPrev,x,y);
            %imagesc(app.groupPrev,im)
           %app.setAxis(app.threshAx,'off','on',"Group 1", "gray");
            %app.gPreveiw.Text = "Refresh";
            
            for i = 1:6
                disp(class(app.GROUPS{i}))
                figure, imagesc(app.GROUPS{i}), title "circ";colormap gray;
            end

        end

        % Button pushed function: StructRun
        function StructRunButtonPushed(app, event)
            im = (app.GROUPS{1,1});
            %ft = fftshift(fft2(fftshift(im)));
            %disp(im)
            [M,N] = size(im);
            [m,n] = meshgrid(-M/2:M/2-1,-N/2:N/2-1);
            disp([M,N])
             filter = zeros(M,N);
             app.SUMC = zeros(N,M);
             app.SUMF = zeros(N,M);
             region = app.RegionDropDown_S.Value - 48;
             if region== 1
                 filter(50:round(N/2-(N*0.19)),round(M/2+(M*0.18)):M-50) = 1; % 1nd quadrant
             elseif region== 2
                 filter(1:round(N/2-(N*0.185)),1:round(M/2-(M*0.185))) = 1;  % 2nd quadrant
             elseif region== 3
                 filter(round(N/2+(N*0.15)):N-40,40:round(M/2-(M*0.18))) = 1; % 3nd quadrant
             else
                 filter(round(N/2+(N*0.075)):N-500,round(M/2+(M*0.07)):M-600) = 1; % 4nd quadrant
             end
             
            % 
            [g, h] = size(app.GROUPS);
            %im = im/g;
            for i = 1:g
                for j = 1:h

                    ims  = app.GROUPS{i,j};%loop
                    ft = fftshift(fft2(fftshift(ims)));
                    filterFt = ft.*filter;
                    ftBin{i,j} = filterFt;
                    %figure, imagesc(abs(filterFt).^0.1), title "filterft";colormap gray;
                    th = app.getThresh(filterFt);

                    circ = app.circ_Struc(filterFt, th,M);
                    ftc = filterFt.*circ;
                    %tit = strcat("ft", string(i),", ",string(h));
                   % figure, imagesc(log(abs(ftc))), title "circ";colormap gray;
                end
            end
            %figure, imagesc(abs(ftBin{2,1}).^0.1), title "ftbin";colormap gray;
            orde = cell(app.NUM_GROUPS, 4);
            for i = 1:2:(app.NUM_GROUPS*app.GROUP_LIM)
                [Cmax1, Rmax1, Cmax2 , Rmax2] = app.max_holo(ftBin{i});
                if ~isempty(Cmax1)
                    %[row, col] = find(cellfun(@(x) isequal(x,ftBin{i}), ftBin{i}))
                    %orde{i,1} = row;
                    r = 1;

                    if i > 1 && i <= 3
                        r = i-1;
                    elseif i > 3
                        r = i-2;
                    else
                        r = i;
                    end
                    orde{r,1} = Cmax1;
                    orde{r,2} = Rmax1;
                    orde{r,3} = Cmax2;
                    orde{r,4} = Rmax2;
                    disp("o")
                end
            end

            %[std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(ftBin{1,1}, ftBin{1,2},0.01, orde{1,1}, orde{1,2}, orde{1,3}, orde{1,4});
            %disp([orde{3,1}, orde{3,2}, orde{3,3}, orde{3,4}])
            %disp([orde{2,1}, orde{2,2}, orde{2,3}, orde{2,4}])
            %[std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(ftBin{2,1}, ftBin{2,2},0.01, orde{2,1}, orde{2,2}, orde{2,3}, orde{2,4});
            %[std_temp_gplus, tempG, angles, FTHolo] = app.minimization(ftBin{2,2}, ftBin{2,1},0.01, orde{2,1}, orde{2,2}, orde{2,3}, orde{2,4});
            %figure, imagesc(app.GROUPS{2,1}), title "holo 2A";colormap gray;
            %figure, imagesc(app.GROUPS{2,2}), title "holo 2B";colormap gray;
            % figure, plot(angles, std_temp_gplus), title 'metric gplus'
            % figure, plot(angles,tempG), title 'metric gminus'
            % disp([orde{2,1}, orde{2,2}, orde{2,3}, orde{2,4}])
            %  [circP, gplus] = app.demodulation_Gminus( std_temp_gplus, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, circ);
            %  [circM, gminus] = app.demodulation_Gminus( tempG, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value,circ);
            % 
            %  percentSimilar = sum(sum(circP == circM)) / numel(circP);
            %  mod = 1;
            %  while percentSimilar > .99
            %      mod = mod + 1;
            %      if mod > 3
            %          mod = 1;
            %      end
            %      [std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(ftBin{2,1}, ftBin{2,2},0.01, orde{mod,1}, orde{mod,2}, orde{mod,3}, orde{mod,4});
            %      [circP, gplus] = app.demodulation_Gminus( std_temp_gplus, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, circ);
            %      [circM, gminus] = app.demodulation_Gminus( tempG, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value,circ);
            %      percentSimilar = sum(sum(circP == circM)) / numel(circP);
            % 
            %      if percentSimilar < .99
            %          break;
            %      end
            % 
            %  end
            %  disp(round(percentSimilar,2))
            % 
            % figure;
            % subplot(1,3,1)
            % imagesc(circP); axis image; colormap gray; title('Mask circ1\_Gp');
            % 
            % subplot(1,3,2)
            % imagesc(abs(gplus)); axis image; colormap gray; title('Amplitude of p');
            % 
            % subplot(1,3,3)
            % imagesc(angle(gplus)); axis image; colormap gray; title('Phase of Gp');
            % 
            % figure;
            % subplot(1,3,1)
            % imagesc(circM); axis image; colormap gray; title('Mask circ1\_Gminus');
            % 
            % subplot(1,3,2)
            % imagesc(abs(gminus)); axis image; colormap gray; title('Amplitude of Gminus');
            % 
            % subplot(1,3,3)
            % imagesc(angle(gminus)); axis image; colormap gray; title('Phase of Gminus');

            
            clear g ft i 
            [g, ft] = size(ftBin);
            for i = 1:g
                 [Cmax2, Rmax2, Cmax1 , Rmax1] = app.max_holo(ftBin{1,1});%NOT SAME AS SOFI
                 [std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(ftBin{i,1}, ftBin{i,2},0.01, orde{i,1}, orde{i,2}, orde{i,3}, orde{i,4});
                 figure, imagesc(abs(ftBin{i,1}).^0.1), title "ftbin a";colormap gray;
                 figure, imagesc(abs(ftBin{i,2}).^0.1), title "ftbin b";colormap gray;
                 [circP, gplus] = app.demodulation_Gminus( std_temp_gplus, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, circ);
                 [circM, gminus] = app.demodulation_Gminus( tempG, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value,circ);
                 
                  percentSimilar = sum(sum(circP == circM)) / numel(circP);
                  mod = i;
                  count = 1;
                  while percentSimilar > .99
                      mod = mod + 1;
                      if mod > 3
                          mod = 1;
                      end
                      [std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(ftBin{2,1}, ftBin{2,2},0.01, orde{mod,1}, orde{mod,2}, orde{mod,3}, orde{mod,4});
                      [circP, gplus] = app.demodulation_Gminus( std_temp_gplus, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, circ);
                      [circM, gminus] = app.demodulation_Gminus( tempG, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value,circ);
                      percentSimilar = sum(sum(circP == circM)) / numel(circP);

                      if percentSimilar < .99
                          break;
                      end

                  end
                  disp(round(percentSimilar,2))

                 app.SUMC = app.SUMC + circP + circM; 
                 app.SUMF = app.SUMF + app.phaseNorm(gplus)+ app.phaseNorm(gminus);
            % 
            %      figure;
            %      subplot(1,3,1)
            %      imagesc(circP); axis image; colormap gray; title('Mask circ1\_Gp');
            % 
            %      subplot(1,3,2)
            %      imagesc(abs(gplus)); axis image; colormap gray; title('Amplitude of p');
            % 
            %      subplot(1,3,3)
            %      imagesc(angle(gplus)); axis image; colormap gray; title('Phase of Gp');
            % 
            %      figure;
            %      subplot(1,3,1)
            %      imagesc(circM); axis image; colormap gray; title('Mask circ1\_Gminus');
            % 
            %      subplot(1,3,2)
            %      imagesc(abs(gminus)); axis image; colormap gray; title('Amplitude of Gminus');
            % 
            %      subplot(1,3,3)
            %      imagesc(angle(gminus)); axis image; colormap gray; title('Phase of Gminus');
            % 
            % 
             end
             figure(20), imagesc(app.SUMC), colormap gray
             fnorm = app.SUMF;
             gsim = fftshift(ifft2(fftshift(fnorm)));
             figure, imagesc(angle(gsim)), colormap gray, title('Phase sim')
             figure, imagesc(abs(fnorm).^0.1), colormap gray, title('FT SIM')
            % ft = fftshift(fft2(fftshift(ims)));
            % %ft2 = fftshift(fft2(fftshift(ims2)));
            % filterFt = ft.*filter;
            % filterFt2 = ft2.*filter;
            % figure; colormap gray;imagesc(abs(filterFt).^0.1);axis image
            % 
            % th = app.getThresh(filterFt);
            % 
            % circ = app.circ_Struc(filterFt, th,M);
            % ftc = filterFt.*circ;
            % figure, imagesc(log(abs(ftc))), title 'circ';colormap gray;
            % [Cmax1, Rmax1, Cmax2 , Rmax2] = app.max_holo(filterFt);%NOT SAME AS SOFI
            % %disp([Cmax1, Rmax1, Cmax2 , Rmax2])
            % step = .01;%rad
            % 
            % [std_temp_gplus, tempG, angles, FTHolo] = app.min_holo(filterFt, filterFt2,step, Cmax1, Rmax1, Cmax2 , Rmax2);
            % figure, plot(angles, std_temp_gplus), title 'metric gplus'
            % figure, plot(angles,tempG), title 'metric gminus'
            % dxy = 4.65; % dx box and dy box BIG CHANGE
            % [circP, gplus] = app.demod("plus", std_temp_gplus, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, (app.RegionDropDown_S.Value-48), circ);
            % [circM, gminus] = app.demod("minus", tempG, angles,FTHolo, app.PixelSizeXumEditField_S.Value,app.PixelSizeYumEditField_S.Value, app.WavelengthumEditField_S.Value, (app.RegionDropDown_S.Value-48),circ);
            % 
            msgbox("done","non-modal")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IndividualOIPhaseTab = uitab(app.TabGroup);
            app.IndividualOIPhaseTab.Title = 'Individual OI Phase';
            app.PhaseAx = uiaxes(app.IndividualOIPhaseTab);
            title(app.PhaseAx, 'Phase')
            xlabel(app.PhaseAx, 'X')
            ylabel(app.PhaseAx, 'Y')
            zlabel(app.PhaseAx, 'Z')
            colormap(app.PhaseAx, 'gray')
            app.PhaseAx.Visible = 'off';
            app.AmpAx = uiaxes(app.IndividualOIPhaseTab);
            title(app.AmpAx, 'Hologram')
            xlabel(app.AmpAx, 'X')
            ylabel(app.AmpAx, 'Y')
            zlabel(app.AmpAx, 'Z')
            colormap(app.AmpAx, 'gray')
            app.AmpAx.Visible = 'off';
            app.PhaseReconstructionButton = uibutton(app.IndividualOIPhaseTab, 'push');
            app.PhaseReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PhaseReconstructionButtonPushed, true);
            app.PhaseReconstructionButton.Text = 'Phase Reconstruction';
            app.LoadButton = uibutton(app.IndividualOIPhaseTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveBox = uicheckbox(app.IndividualOIPhaseTab);
            app.SaveBox.Text = 'Save Phase maps';
            app.tFile = uieditfield(app.IndividualOIPhaseTab, 'text');
            app.tFile.Editable = 'off';
            app.ParametersPanel = uipanel(app.IndividualOIPhaseTab);
            app.ParametersPanel.Title = 'Parameters:';
            app.samPerams = uicheckbox(app.ParametersPanel);
            app.samPerams.Text = 'Same peramiters';
            app.samPerams.Value = true;
            app.PixelSizeYumEditField = uieditfield(app.IndividualOIPhaseTab, 'numeric');
            app.PixelSizeXumEditFieldLabel = uilabel(app.IndividualOIPhaseTab);
            app.PixelSizeXumEditFieldLabel.Text = 'Pixel Size X (um)';
            app.PixelSizeXumEditField = uieditfield(app.IndividualOIPhaseTab, 'numeric');
            app.PixelSizeYumEditFieldLabel = uilabel(app.IndividualOIPhaseTab);
            app.PixelSizeYumEditFieldLabel.Text = 'Pixel Size Y (um)';
            app.WavelengthumEditFieldLabel = uilabel(app.IndividualOIPhaseTab);
            app.WavelengthumEditFieldLabel.Text = 'Wavelength (um)';
            app.WavelengthumEditField = uieditfield(app.IndividualOIPhaseTab, 'numeric');
            app.PR = uibutton(app.IndividualOIPhaseTab, 'push');
            app.PR.ButtonPushedFcn = createCallbackFcn(app, @previewNext, true);
            app.PR.Visible = 'off';
            app.PR.Text = '>';
            app.PL = uibutton(app.IndividualOIPhaseTab, 'push');
            app.PL.ButtonPushedFcn = createCallbackFcn(app, @previewBack, true);
            app.PL.Visible = 'off';
            app.PL.Text = '<';
            app.RegionDropDownLabel = uilabel(app.IndividualOIPhaseTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.IndividualOIPhaseTab);
            app.RegionDropDown.Items = {'1', '2', '3', '4'};
            app.RegionDropDown.ItemsData = {'1', '2', '3', '4'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = '1';
            app.Image = uiimage(app.IndividualOIPhaseTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            app.Label = uilabel(app.IndividualOIPhaseTab);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.FTPSwitch = uiswitch(app.IndividualOIPhaseTab, 'slider');
            app.FTPSwitch.Items = {'FT', 'Phase'};
            app.FTPSwitch.ValueChangedFcn = createCallbackFcn(app, @FTPSwitchValueChanged, true);
            app.FTPSwitch.Visible = 'off';
            app.FTPSwitch.Tooltip = {'Change preveiw between the Fourier transform (FT) and the Phase map (Phase) of the current image'};
            app.FTPSwitch.Value = 'Phase';
            app.RegionEditField = uieditfield(app.IndividualOIPhaseTab, 'numeric');
            app.RegionEditField.Editable = 'off';
            app.RegionEditField.Visible = 'off';
            app.RegionEditFieldLabel = uilabel(app.IndividualOIPhaseTab);
            app.RegionEditFieldLabel.Visible = 'off';
            app.RegionEditFieldLabel.Text = 'Region';
            app.ConjugateCheckBox = uicheckbox(app.IndividualOIPhaseTab);
            app.ConjugateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConjugateCheckBoxValueChanged, true);
            app.ConjugateCheckBox.Visible = 'off';
            app.ConjugateCheckBox.Text = 'Conjugate';
            app.StructuredTab = uitab(app.TabGroup);
            app.StructuredTab.Title = 'Structured';
            app.groupPrev = uiaxes(app.StructuredTab);
            title(app.groupPrev, 'Hologram')
            xlabel(app.groupPrev, 'X')
            ylabel(app.groupPrev, 'Y')
            zlabel(app.groupPrev, 'Z')
            app.groupPrev.Visible = 'off';
            app.StructLoad = uibutton(app.StructuredTab, 'push');
            app.StructLoad.ButtonPushedFcn = createCallbackFcn(app, @StructLoadButtonPushed, true);
            app.StructLoad.Text = 'Load';
            app.NumberofgroupsEditFieldLabel = uilabel(app.StructuredTab);
            app.NumberofgroupsEditFieldLabel.Text = 'Number of groups';
            app.groupNUM = uieditfield(app.StructuredTab, 'numeric');
            app.gSelect = uipanel(app.StructuredTab);
            app.gSelect.Title = 'Group selection';
            app.ftSwitch = uiswitch(app.StructuredTab, 'slider');
            app.ftSwitch.Items = {'holo', 'FT'};
            app.ftSwitch.Value = 'holo';
            app.gP = uibutton(app.StructuredTab, 'push');
            app.gP.Text = '<';
            app.gN = uibutton(app.StructuredTab, 'push');
            app.gN.Text = '>';
            app.gPreveiw = uibutton(app.StructuredTab, 'push');
            app.gPreveiw.ButtonPushedFcn = createCallbackFcn(app, @gPreveiwButtonPushed, true);
            app.gPreveiw.Visible = 'off';
            app.gPreveiw.Text = 'Preveiw';
            app.ParametersPanel_S = uipanel(app.StructuredTab);
            app.ParametersPanel_S.Title = 'Parameters:';
            app.ParametersPanel_S.Visible = 'off';
            app.samPerams_2 = uicheckbox(app.ParametersPanel_S);
            app.samPerams_2.Text = 'Same peramiters';
            app.samPerams_2.Value = true;
            app.PixelSizeYumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.PixelSizeYumEditField_2Label.Text = 'Pixel Size Y (um)';
            app.PixelSizeYumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.PixelSizeXumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.PixelSizeXumEditField_2Label.Text = 'Pixel Size X (um)';
            app.PixelSizeXumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.WavelengthumEditField_2Label = uilabel(app.ParametersPanel_S);
            app.WavelengthumEditField_2Label.Text = 'Wavelength (um)';
            app.WavelengthumEditField_S = uieditfield(app.ParametersPanel_S, 'numeric');
            app.RegionDropDown_2Label = uilabel(app.ParametersPanel_S);
            app.RegionDropDown_2Label.Text = 'Region';
            app.RegionDropDown_S = uidropdown(app.ParametersPanel_S);
            app.RegionDropDown_S.Items = {'1', '2', '3', '4'};
            app.RegionDropDown_S.ItemsData = {'1', '2', '3', '4'};
            app.RegionDropDown_S.Value = '1';
            app.StructRun = uibutton(app.StructuredTab, 'push');
            app.StructRun.ButtonPushedFcn = createCallbackFcn(app, @StructRunButtonPushed, true);
            app.StructRun.Visible = 'off';
            app.StructRun.Text = 'Run';
            app.SRPhaseTab = uitab(app.TabGroup);
            app.SRPhaseTab.Title = 'SR Phase';
            app.threshAx = uiaxes(app.SRPhaseTab);
            title(app.threshAx, 'Super-resolved phase map')
            colormap(app.threshAx, 'gray')
            app.threshAx.Visible = 'off';
            app.Paramiters2 = uipanel(app.SRPhaseTab);
            app.Paramiters2.Title = 'Parameters:';
            app.SRQPIMapButton = uibutton(app.Paramiters2, 'push');
            app.SRQPIMapButton.ButtonPushedFcn = createCallbackFcn(app, @SRQPIMapButtonPushed, true);
            app.SRQPIMapButton.Text = 'SR QPI Map';
            app.RefractiveIndexSamplensEditFieldLabel = uilabel(app.Paramiters2);
            app.RefractiveIndexSamplensEditFieldLabel.Text = 'Refractive Index Sample (ns):';
            app.NS = uieditfield(app.Paramiters2, 'numeric');
            app.RefactiveInexMediumnimEditFieldLabel = uilabel(app.Paramiters2);
            app.RefactiveInexMediumnimEditFieldLabel.Text = 'Refactive Inex Medium (nim):';
            app.NIM = uieditfield(app.Paramiters2, 'numeric');
            app.Image_2 = uiimage(app.SRPhaseTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'logo_OIRL_nobackground.png');
            app.InformationPanel = uipanel(app.SRPhaseTab);
            app.InformationPanel.Title = 'Information';
            app.InformationPanel.Visible = 'off';
            app.ThickSwitch = uiswitch(app.InformationPanel, 'slider');
            app.ThickSwitch.Items = {'Power spectrum', 'Phase'};
            app.ThickSwitch.ValueChangedFcn = createCallbackFcn(app, @ThickSwitchValueChanged, true);
            app.ThickSwitch.Value = 'Power spectrum';
            app.Stats = uilabel(app.InformationPanel);
            app.ThicknessCheckBox = uicheckbox(app.InformationPanel);
            app.ThicknessCheckBox.Text = 'Thickness';
            app.Welcome = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.Welcome);
            app.Tab_2.Title = 'Tab';
            app.ObliqueIlluminationButton = uibutton(app.Tab_2, 'push');
            app.ObliqueIlluminationButton.Text = 'Oblique Illumination';
            app.StructuredIlluminationButton = uibutton(app.Tab_2, 'push');
            app.StructuredIlluminationButton.Text = 'Structured Illumination';
            app.WelcomeLabel = uilabel(app.Tab_2);
            app.WelcomeLabel.Text = 'Welcome';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gooApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end